classdef testCase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout4                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        ImportTab                     matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        ImportfilesPanel              matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        ErrorLabel                    matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        AreaofimagemEditField         matlab.ui.control.NumericEditField
        AreaofimagemEditFieldLabel    matlab.ui.control.Label
        SearchFileButton_2            matlab.ui.control.Button
        SearchFileButton              matlab.ui.control.Button
        plyFile                       matlab.ui.control.EditField
        content2_2                    matlab.ui.control.Label
        bagFile                       matlab.ui.control.EditField
        content2                      matlab.ui.control.Label
        RGBImg                        matlab.ui.control.UIAxes
        DepthImg                      matlab.ui.control.UIAxes
        ExamineTab                    matlab.ui.container.Tab
        TabGroup3                     matlab.ui.container.TabGroup
        DepthTab                      matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        LayerdefinitionPanel          matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        ApplyButton                   matlab.ui.control.Button
        LayerpickerSlider             matlab.ui.control.Slider
        LayerpickerSliderLabel        matlab.ui.control.Label
        LayerThicknessEditField       matlab.ui.control.NumericEditField
        LayerThicknessEditFieldLabel  matlab.ui.control.Label
        ResetLayerHeightButton        matlab.ui.control.Button
        ShowLayerSpinner              matlab.ui.control.Spinner
        ShowLayerSpinnerLabel         matlab.ui.control.Label
        AutoplanerotationPanel        matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        ResetAutoRotationButton       matlab.ui.control.Button
        ZNormSwitch                   matlab.ui.control.Switch
        ZSwitchLabel                  matlab.ui.control.Label
        YNormSwitch                   matlab.ui.control.Switch
        YSwitchLabel                  matlab.ui.control.Label
        XNormSwitch                   matlab.ui.control.Switch
        XSwitchLabel                  matlab.ui.control.Label
        AutoRotateButton              matlab.ui.control.Button
        ManualplanerotationPanel      matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        ResetManualRotationButton     matlab.ui.control.Button
        x_rotationSpinner             matlab.ui.control.Spinner
        x_rotationSpinnerLabel        matlab.ui.control.Label
        y_rotationSpinner             matlab.ui.control.Spinner
        y_rotationSpinnerLabel        matlab.ui.control.Label
        z_rotationSpinner_2           matlab.ui.control.Spinner
        z_rotationSpinner_2Label      matlab.ui.control.Label
        DepthImgAdjusted              matlab.ui.control.UIAxes
        ImageTab                      matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        GreendetectionPanel           matlab.ui.container.Panel
        LAITab                        matlab.ui.container.Tab
        ExportTab                     matlab.ui.container.Tab
        GetDataButton                 matlab.ui.control.Button
        ExportasDropDown              matlab.ui.control.DropDown
        ExportasDropDownLabel         matlab.ui.control.Label
        madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel  matlab.ui.control.Label
        Hyperlink                     matlab.ui.control.Hyperlink
    end

    
    properties (Access = private)
        rawBagData      % The data imported from the .bag file untouched
        rawPlyData      % The data imported from the .bag file untouched
        originalRGBImg  % The original RGB image extracted from the .bag file
        %scaledRGBImg    % RGB image scaled with depth view

        originalDepthImg        % The original Depth image extracted from the .bag file
        %sizeOriginalDepthImg   % The width (2) and height(1) data
        %correctedDepthImg         % Depth image cropped with rectangle

        filePathBag % The path to the .bag file
        filePathPly % The path to the .ply file
        areaMSquare % The given area of the data
    end
    
    methods (Access = private)
        
        function ComputeBagData(app, bagData)

            curSelColor = select(bagData,'Topic','/device_0/sensor_1/Color_0/image/data');
            allImColor = readMessages(curSelColor, 'DataFormat', 'struct');
            app.originalRGBImg = double(rosReadImage(allImColor{1}))/255.0;

            % curSelDepth = select(bagData,'Topic','/device_0/sensor_0/Depth_0/image/data');
            % allImDepth = readMessages(curSelDepth, 'DataFormat', 'struct');
            % imDepth = double(rosReadImage(allImDepth{1}));
            % %imDepthNormalised = imDepth / max(max(imDepth));
            % 
            % curSelDepth = select(bagData,'Topic','/device_0/sensor_0/option/Depth_Units/value');
            % depthVal = readMessages(curSelDepth, 'DataFormat', 'struct');
            % imDepthReal = imDepth .* depthVal{1}.Data; 
            % imDepthReal(imDepthReal == 0) = NaN; % Change the zero data with NaN.
            % 
            % app.originalDepthImg = imDepthReal;

        end
        
        function DispImg(app, img, parent)
            imshow(img, 'Parent',parent);
        end

        function DispImg3d(app, img, parent)
            surf(img, "EdgeColor","none", 'Parent', parent)
            %scatfig.Children(2).Parent = parent
        end
        
        function results = ComputePlyData(app, plyData)
            %[model,inlierIndices, outlierIndices] = pcfitplane(plyData,0.5);
            app.originalDepthImg = plyData; %select(plyData, inlierIndices);
        end
        
        function DispPCImg(app, img, parent)
            pcshow(img, 'Parent',parent)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadFile(app, event)
            % reset the current values
            clc;
            
            %path = "ImgLoad/";
            bagFile = app.bagFile.Value;
            plyFile = app.plyFile.Value;
            
            errorFlag = 0;

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                app.rawBagData = rosbag(bagFile);
                app.rawPlyData = pcread(plyFile);

            catch
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                app.areaMSquare = app.AreaofimagemEditField.Value;
                disp(app.areaMSquare)
            catch
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                end
            end

            %try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                ComputeBagData(app, app.rawBagData); % Compute the image as rgb and depth image and return this
                ComputePlyData(app, app.rawPlyData); 
           % catch
                % if errorFlag == 0 % If no error has occured so far
                %     errorFlag = 1; % Set the error flag
                %     app.ErrorLabel.Visible = "on"; % Show error when file not found
                %     app.ErrorLabel.Text = "Can't compute data.";
                % end
            %end 
            % https://www.mathworks.com/matlabcentral/answers/418893-how-to-display-an-image-in-ui-figure-on-app-designer

            DispImg(app, app.originalRGBImg, app.RGBImg);
            DispPCImg(app, app.rawPlyData, app.DepthImg);

            % set the images on the other tabs

            % Set Examine - Depth
            DispPCImg(app, app.rawPlyData, app.DepthImgAdjusted);
            % We have to change this accordingly to the stuff we do on it.

            % Set the crop values
            %app.ResetCropValues();

        end

        % Button pushed function: SearchFileButton
        function SearchFileButtonPushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                [file,path] = uigetfile('*.bag');
                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    disp(['User selected ', fullfile(path,file)]);
                    app.filePathBag = fullfile(path,file); %Transfer filepath
                    app.bagFile.Value = app.filePathBag; % set filepath in editable field
                end
            catch
                disp("something wrong with the file");
            end

            % 
        end

        % Button pushed function: SearchFileButton_2
        function SearchFileButton_2Pushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                [file,path] = uigetfile('*.ply');
                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    disp(['User selected ', fullfile(path,file)]);
                    app.filePathPly = fullfile(path,file); %Transfer filepath
                    app.plyFile.Value = app.filePathPly; % set filepath in editable field
                end
            catch
                disp("something wrong with the file");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://github.com/RobbeElsermans/LAI_detection';
            app.Hyperlink.Text = 'GitHub';
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel = uilabel(app.GridLayout4);
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel.Text = 'made by: Robbe Elsermans, Thomas Kramp and Ahmad Shakleya';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Tooltip = {'Import data to work with.'};
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.DepthImg = uiaxes(app.GridLayout);
            title(app.DepthImg, 'Depth')
            app.DepthImg.XTick = [];
            app.DepthImg.YTick = [];
            app.RGBImg = uiaxes(app.GridLayout);
            title(app.RGBImg, 'RGB')
            app.RGBImg.XTick = [];
            app.RGBImg.YTick = [];
            app.ImportfilesPanel = uipanel(app.GridLayout);
            app.ImportfilesPanel.Title = 'Import files';
            app.GridLayout9 = uigridlayout(app.ImportfilesPanel);
            app.content2 = uilabel(app.GridLayout9);
            app.content2.Text = 'Path';
            app.bagFile = uieditfield(app.GridLayout9, 'text');
            app.bagFile.Placeholder = 'c:/img1.bat';
            app.content2_2 = uilabel(app.GridLayout9);
            app.content2_2.Text = 'Path';
            app.plyFile = uieditfield(app.GridLayout9, 'text');
            app.plyFile.Placeholder = 'c:/img1.ply';
            app.SearchFileButton = uibutton(app.GridLayout9, 'push');
            app.SearchFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButtonPushed, true);
            app.SearchFileButton.Text = 'Search File';
            app.SearchFileButton_2 = uibutton(app.GridLayout9, 'push');
            app.SearchFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButton_2Pushed, true);
            app.SearchFileButton_2.Text = 'Search File';
            app.AreaofimagemEditFieldLabel = uilabel(app.GridLayout9);
            app.AreaofimagemEditFieldLabel.Text = 'Area of image (m²)';
            app.AreaofimagemEditField = uieditfield(app.GridLayout9, 'numeric');
            app.AreaofimagemEditField.Limits = [0 Inf];
            app.AreaofimagemEditField.ValueDisplayFormat = '%0.0f m²';
            app.AreaofimagemEditField.Placeholder = 'm²';
            app.LoadDataButton = uibutton(app.GridLayout9, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ErrorLabel = uilabel(app.GridLayout9);
            app.ErrorLabel.Tag = 'FileImportError';
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.ExamineTab = uitab(app.TabGroup);
            app.ExamineTab.Tooltip = {''};
            app.ExamineTab.Title = 'Examine';
            app.TabGroup3 = uitabgroup(app.ExamineTab);
            app.DepthTab = uitab(app.TabGroup3);
            app.DepthTab.Tooltip = {''};
            app.DepthTab.Title = 'Depth';
            app.GridLayout5 = uigridlayout(app.DepthTab);
            app.DepthImgAdjusted = uiaxes(app.GridLayout5);
            title(app.DepthImgAdjusted, 'Point Cloud')
            xlabel(app.DepthImgAdjusted, 'X')
            ylabel(app.DepthImgAdjusted, 'Y')
            zlabel(app.DepthImgAdjusted, 'Z')
            app.ManualplanerotationPanel = uipanel(app.GridLayout5);
            app.ManualplanerotationPanel.Title = 'Manual plane rotation';
            app.GridLayout7 = uigridlayout(app.ManualplanerotationPanel);
            app.z_rotationSpinner_2Label = uilabel(app.GridLayout7);
            app.z_rotationSpinner_2Label.Text = 'z_rotation';
            app.z_rotationSpinner_2 = uispinner(app.GridLayout7);
            app.y_rotationSpinnerLabel = uilabel(app.GridLayout7);
            app.y_rotationSpinnerLabel.Text = 'y_rotation';
            app.y_rotationSpinner = uispinner(app.GridLayout7);
            app.x_rotationSpinnerLabel = uilabel(app.GridLayout7);
            app.x_rotationSpinnerLabel.Text = 'x_rotation';
            app.x_rotationSpinner = uispinner(app.GridLayout7);
            app.ResetManualRotationButton = uibutton(app.GridLayout7, 'push');
            app.ResetManualRotationButton.Text = 'Reset';
            app.AutoplanerotationPanel = uipanel(app.GridLayout5);
            app.AutoplanerotationPanel.Title = 'Auto plane rotation';
            app.GridLayout8 = uigridlayout(app.AutoplanerotationPanel);
            app.AutoRotateButton = uibutton(app.GridLayout8, 'push');
            app.AutoRotateButton.Text = 'Auto Rotate';
            app.XSwitchLabel = uilabel(app.GridLayout8);
            app.XSwitchLabel.Text = 'X';
            app.XNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.XNormSwitch.Items = {'0', '1'};
            app.XNormSwitch.Value = '0';
            app.YSwitchLabel = uilabel(app.GridLayout8);
            app.YSwitchLabel.Text = 'Y';
            app.YNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.YNormSwitch.Items = {'0', '1'};
            app.YNormSwitch.Value = '0';
            app.ZSwitchLabel = uilabel(app.GridLayout8);
            app.ZSwitchLabel.Text = 'Z';
            app.ZNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.ZNormSwitch.Items = {'0', '1'};
            app.ZNormSwitch.Value = '0';
            app.ResetAutoRotationButton = uibutton(app.GridLayout8, 'push');
            app.ResetAutoRotationButton.Text = 'Reset';
            app.LayerdefinitionPanel = uipanel(app.GridLayout5);
            app.LayerdefinitionPanel.Title = 'Layer definition';
            app.GridLayout10 = uigridlayout(app.LayerdefinitionPanel);
            app.ShowLayerSpinnerLabel = uilabel(app.GridLayout10);
            app.ShowLayerSpinnerLabel.Text = 'Show Layer';
            app.ShowLayerSpinner = uispinner(app.GridLayout10);
            app.ShowLayerSpinner.Limits = [0 Inf];
            app.ResetLayerHeightButton = uibutton(app.GridLayout10, 'push');
            app.ResetLayerHeightButton.Text = 'Reset';
            app.LayerThicknessEditFieldLabel = uilabel(app.GridLayout10);
            app.LayerThicknessEditFieldLabel.Text = 'Layer Thickness';
            app.LayerThicknessEditField = uieditfield(app.GridLayout10, 'numeric');
            app.LayerpickerSliderLabel = uilabel(app.GridLayout10);
            app.LayerpickerSliderLabel.Text = 'Layer picker';
            app.LayerpickerSlider = uislider(app.GridLayout10);
            app.ApplyButton = uibutton(app.GridLayout10, 'push');
            app.ApplyButton.Text = 'Apply';
            app.ImageTab = uitab(app.TabGroup3);
            app.ImageTab.Title = 'Image';
            app.GridLayout6 = uigridlayout(app.ImageTab);
            app.GreendetectionPanel = uipanel(app.GridLayout6);
            app.GreendetectionPanel.Title = 'Green detection';
            app.LAITab = uitab(app.TabGroup3);
            app.LAITab.Tooltip = {'Compute Leave Area Index and otpion to finetune the used equasions.'};
            app.LAITab.Title = 'LAI';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Tooltip = {'Export the data'};
            app.ExportTab.Title = 'Export';
            app.ExportasDropDownLabel = uilabel(app.ExportTab);
            app.ExportasDropDownLabel.Text = 'Export as';
            app.ExportasDropDown = uidropdown(app.ExportTab);
            app.GetDataButton = uibutton(app.ExportTab, 'push');
            app.GetDataButton.Text = 'Get Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testCase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end